classdef demo_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button_2  matlab.ui.control.Button
        Label     matlab.ui.control.Label
        Button    matlab.ui.control.Button
    end

    
    properties (Access = private)
        collectdata % send data from to arduino
        senddata % read data from arduino
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.a = arduino("COM3", "Uno");
            %app.a = serialport("COM3", 9600);

            app.collectdata=serialport('COM3',9600, "Timeout", 5); % create serial communication object on port COM3 
            
            %app.senddata=serialport('COM3','BaudRate',9600); % create serial communication object on port COM3 
            
            %configurePin(app.a, "D34", "AnalogInput");

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            value = readline(app.collectdata);
            %value = readVoltage(app.a, "D34");
            %app.Gauge.Value = value;
            app.Label.Text = char(string(value));
            clear app.collectdata;
        end

        % Callback function
        function Button2Pushed(app, event)
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            answer = "Hello arduino";
            fopen(app.collectdata); % initiate arduino communication
            
            fprintf(app.collectdata,"%s",answer); % send answer to arduino
            fclose(app.collectdata); % end communication with arduino
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Label = uilabel(app.UIFigure);
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demo_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end